classdef TMatrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        ProcesarButton             matlab.ui.control.Button
        ParmetrosElctricosPanel    matlab.ui.container.Panel
        BLEditFieldLabel           matlab.ui.control.Label
        BLEditField                matlab.ui.control.NumericEditField
        LeEditFieldLabel           matlab.ui.control.Label
        LeEditField                matlab.ui.control.NumericEditField
        ReEditFieldLabel           matlab.ui.control.Label
        ReEditField                matlab.ui.control.NumericEditField
        OhmsLabel                  matlab.ui.control.Label
        mHyLabel                   matlab.ui.control.Label
        TmLabel                    matlab.ui.control.Label
        ParmetrosmecnicosPanel     matlab.ui.container.Panel
        MmEditFieldLabel           matlab.ui.control.Label
        MmEditField                matlab.ui.control.NumericEditField
        CmsEditFieldLabel          matlab.ui.control.Label
        CmsEditField               matlab.ui.control.NumericEditField
        RmsEditFieldLabel          matlab.ui.control.Label
        RmsEditField               matlab.ui.control.NumericEditField
        DsEditFieldLabel           matlab.ui.control.Label
        DsEditField                matlab.ui.control.NumericEditField
        gLabel                     matlab.ui.control.Label
        umNLabel                   matlab.ui.control.Label
        KgsLabel                   matlab.ui.control.Label
        mLabel_3                   matlab.ui.control.Label
        ParmetrosacsticosPanel     matlab.ui.container.Panel
        LportEditFieldLabel        matlab.ui.control.Label
        LportEditField             matlab.ui.control.NumericEditField
        DportEditFieldLabel        matlab.ui.control.Label
        DportEditField             matlab.ui.control.NumericEditField
        RapEditFieldLabel          matlab.ui.control.Label
        RapEditField               matlab.ui.control.NumericEditField
        VboxEditFieldLabel         matlab.ui.control.Label
        VboxEditField              matlab.ui.control.NumericEditField
        mLabel                     matlab.ui.control.Label
        mLabel_2                   matlab.ui.control.Label
        Nsm5Label                  matlab.ui.control.Label
        m3Label                    matlab.ui.control.Label
        TMatrixAppLabel            matlab.ui.control.Label
        TipodegabineteButtonGroup  matlab.ui.container.ButtonGroup
        VentiladoButton            matlab.ui.control.RadioButton
        CerradoButton              matlab.ui.control.RadioButton
        BaffleinfinitoButton       matlab.ui.control.RadioButton
        ExportarRIButton           matlab.ui.control.Button
    end


    properties (Access = private)
        RI_MP % Vector RI de fase mínima para exportar
        RI_LP % Vector RI de fase lineal para exportar
    end


    methods (Access = private)

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
        %% Parámetros Eléctricos del motor %%
        BL = app.BLEditField.Value ; % Motor magnetico 
        Le = app.LeEditField.Value/1000 ; % Inductancia de la bobina
        Re = app.ReEditField.Value ; % Resistencia electríca de la bobina
        %% Parámetros Mecánicos del motor %%
        Mm = app.MmEditField.Value /1000 ; % Masa mecánica del diafragma
        Cms = app.CmsEditField.Value /1e6 ; % Compliancia mecánica de la suspensión
        Rms = app.RmsEditField.Value ; % Resistencia mecánica del motor lineal
        Ds = app.DsEditField.Value ; % Diametro del diafragma
        %% Parámetros acústicos del gabinete %%
        Lport = app.LportEditField.Value ; % Largo del port 
        Dport = app.DportEditField.Value ; % Díametro del port
        Rap= app.RapEditField.Value ; % Resistencia acústica del port
        Vbox = app.VboxEditField.Value ; % Volumen del gabinete
        if app.VentiladoButton.Value==1
        type= 2; % Bafle Ventilado
        end
        if app.CerradoButton.Value==1
        type= 1; % Bafle cerrado
        end
        if app.BaffleinfinitoButton.Value==1
        type= 0; % Baffle infinito
        end
        
        [H,f,ftg,tg,hlp,fmotor,fbox,fc,htf]  = MATRIZ(BL,Re,Le,Mm,Cms,Rms,Ds,Lport,Dport,Rap,Vbox,type);
        app.RI_MP.Value= htf;
        app.RI_LP.Value= hlp;
        semilogx(app.UIAxes,f,db(H(1:length(f))),'k','linewidth',2);
        
        fmotor=num2str(round(fmotor));
        fbox=num2str(round(fbox));
        fc=num2str(round(fc));
        ylabel(app.UIAxes,'dB SPL');
        xlabel(app.UIAxes,'Frequency [kHz]');
        xticks(app.UIAxes,[31.5,65,125,250,500,1000,2000,4000,8000]);
        xlim(app.UIAxes,[31.5,8e3]);
        yticks(app.UIAxes,88:3:100);
        ylim(app.UIAxes,[88,100]);
        grid(app.UIAxes,'on');
        if app.VentiladoButton.Value==1
            legend(app.UIAxes,strcat('Fs=',fmotor,' Hz,','Fbox=',fbox,' Hz'));
        elseif app.CerradoButton.Value==1
            legend(app.UIAxes,strcat('Fs=',fmotor,' Hz,','Fc=',fc,' Hz'));
        else
            legend(app.UIAxes,strcat('Fs=',fmotor,' Hz'))
        end
        
        semilogx(app.UIAxes_2,ftg,tg,'k','linewidth',2);
        
        ylabel(app.UIAxes_2,'Group Delay');
        xlabel(app.UIAxes_2,'Frequency [kHz]');
        xticks(app.UIAxes_2,[31.5,40,50,63,125,250,500,1000,2000,4000,8000]);
        xlim(app.UIAxes_2,[31.5,7999]);
        yticks(app.UIAxes_2,0:2:20);
        ylim(app.UIAxes_2,[0,20]);
        grid(app.UIAxes_2,'on');
        
        end

        % Button pushed function: ExportarRIButton
        function ExportarRIButtonPushed(app, event)
           htf = app.RI_MP.Value;
           hlp = app.RI_LP.Value;
           audiowrite('htf.wav',htf,16e3);
           audiowrite('hlp.wav',hlp,16e3); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta en frecuencia')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Retardo de grupo')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ProcesarButton = uibutton(app.UIFigure, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = 'Procesar';
            app.ParmetrosElctricosPanel = uipanel(app.UIFigure);
            app.ParmetrosElctricosPanel.Title = 'Parámetros Eléctricos ';
            app.BLEditFieldLabel = uilabel(app.ParmetrosElctricosPanel);
            app.BLEditFieldLabel.Text = 'BL';
            app.BLEditField = uieditfield(app.ParmetrosElctricosPanel, 'numeric');
            app.BLEditField.Value = 9.68;
            app.LeEditFieldLabel = uilabel(app.ParmetrosElctricosPanel);
            app.LeEditFieldLabel.Text = 'Le';
            app.LeEditField = uieditfield(app.ParmetrosElctricosPanel, 'numeric');
            app.LeEditField.Value = 0.49;
            app.ReEditFieldLabel = uilabel(app.ParmetrosElctricosPanel);
            app.ReEditFieldLabel.Text = 'Re';
            app.ReEditField = uieditfield(app.ParmetrosElctricosPanel, 'numeric');
            app.ReEditField.Value = 5.99;
            app.OhmsLabel = uilabel(app.ParmetrosElctricosPanel);
            app.OhmsLabel.Text = 'Ohms';
            app.mHyLabel = uilabel(app.ParmetrosElctricosPanel);
            app.mHyLabel.Text = 'mHy';
            app.TmLabel = uilabel(app.ParmetrosElctricosPanel);
            app.TmLabel.Text = 'Tm';
            app.ParmetrosmecnicosPanel = uipanel(app.UIFigure);
            app.ParmetrosmecnicosPanel.Title = 'Parámetros mecánicos ';
            app.MmEditFieldLabel = uilabel(app.ParmetrosmecnicosPanel);
            app.MmEditFieldLabel.Text = 'Mm';
            app.MmEditField = uieditfield(app.ParmetrosmecnicosPanel, 'numeric');
            app.MmEditField.Value = 16.11;
            app.CmsEditFieldLabel = uilabel(app.ParmetrosmecnicosPanel);
            app.CmsEditFieldLabel.Text = 'Cms';
            app.CmsEditField = uieditfield(app.ParmetrosmecnicosPanel, 'numeric');
            app.CmsEditField.Value = 372;
            app.RmsEditFieldLabel = uilabel(app.ParmetrosmecnicosPanel);
            app.RmsEditFieldLabel.Text = 'Rms';
            app.RmsEditField = uieditfield(app.ParmetrosmecnicosPanel, 'numeric');
            app.RmsEditField.Value = 1.64;
            app.DsEditFieldLabel = uilabel(app.ParmetrosmecnicosPanel);
            app.DsEditFieldLabel.Text = 'Ds';
            app.DsEditField = uieditfield(app.ParmetrosmecnicosPanel, 'numeric');
            app.DsEditField.Value = 0.16;
            app.gLabel = uilabel(app.ParmetrosmecnicosPanel);
            app.gLabel.Text = 'g';
            app.umNLabel = uilabel(app.ParmetrosmecnicosPanel);
            app.umNLabel.Text = 'um/N';
            app.KgsLabel = uilabel(app.ParmetrosmecnicosPanel);
            app.KgsLabel.Text = 'Kg/s';
            app.mLabel_3 = uilabel(app.ParmetrosmecnicosPanel);
            app.mLabel_3.Text = 'm';
            app.ParmetrosacsticosPanel = uipanel(app.UIFigure);
            app.ParmetrosacsticosPanel.Title = 'Parámetros acústicos';
            app.LportEditFieldLabel = uilabel(app.ParmetrosacsticosPanel);
            app.LportEditFieldLabel.Text = 'Lport';
            app.LportEditField = uieditfield(app.ParmetrosacsticosPanel, 'numeric');
            app.LportEditField.Value = 0.1625;
            app.DportEditFieldLabel = uilabel(app.ParmetrosacsticosPanel);
            app.DportEditFieldLabel.Text = 'Dport';
            app.DportEditField = uieditfield(app.ParmetrosacsticosPanel, 'numeric');
            app.DportEditField.Value = 0.063;
            app.RapEditFieldLabel = uilabel(app.ParmetrosacsticosPanel);
            app.RapEditFieldLabel.Text = 'Rap';
            app.RapEditField = uieditfield(app.ParmetrosacsticosPanel, 'numeric');
            app.VboxEditFieldLabel = uilabel(app.ParmetrosacsticosPanel);
            app.VboxEditFieldLabel.Text = 'Vbox';
            app.VboxEditField = uieditfield(app.ParmetrosacsticosPanel, 'numeric');
            app.VboxEditField.Value = 0.014;
            app.mLabel = uilabel(app.ParmetrosacsticosPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.ParmetrosacsticosPanel);
            app.mLabel_2.Text = 'm';
            app.Nsm5Label = uilabel(app.ParmetrosacsticosPanel);
            app.Nsm5Label.Text = 'Ns/m^5';
            app.m3Label = uilabel(app.ParmetrosacsticosPanel);
            app.m3Label.Text = 'm^3';
            app.TMatrixAppLabel = uilabel(app.UIFigure);
            app.TMatrixAppLabel.Text = 'TMatrix  App ';
            app.TipodegabineteButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodegabineteButtonGroup.Title = 'Tipo de gabinete';
            app.VentiladoButton = uiradiobutton(app.TipodegabineteButtonGroup);
            app.VentiladoButton.Text = 'Ventilado';
            app.VentiladoButton.Value = true;
            app.CerradoButton = uiradiobutton(app.TipodegabineteButtonGroup);
            app.CerradoButton.Text = 'Cerrado';
            app.BaffleinfinitoButton = uiradiobutton(app.TipodegabineteButtonGroup);
            app.BaffleinfinitoButton.Text = 'Baffle infinito';
            app.ExportarRIButton = uibutton(app.UIFigure, 'push');
            app.ExportarRIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarRIButtonPushed, true);
            app.ExportarRIButton.Text = 'Exportar RI';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TMatrix

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end